classdef TestLayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LogsTextArea                    matlab.ui.control.TextArea
        LogsTextAreaLabel               matlab.ui.control.Label
        DriveawayfromobjectButton       matlab.ui.control.Button
        ManualControlPanel              matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        tcpCordZ                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        TCPcoordinatesLabel             matlab.ui.control.Label
        tcpCordY                        matlab.ui.control.Label
        tcpCordX                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        StepsizeSpinner                 matlab.ui.control.Spinner
        StepsizeSpinnerLabel            matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ManualYLeftBtn                  matlab.ui.control.Button
        manualYRightBtn                 matlab.ui.control.Button
        MinimumdistancetoobjectSpinner  matlab.ui.control.Spinner
        MinimumdistancetoobjectSpinnerLabel  matlab.ui.control.Label
        CoordinateSystemDropDown        matlab.ui.control.DropDown
        CoordinateSystemDropDownLabel   matlab.ui.control.Label
        ManualZDownBtn                  matlab.ui.control.Button
        manualXRightBtn                 matlab.ui.control.Button
        ManualXLeftBtn                  matlab.ui.control.Button
        ManualZUpBtn                    matlab.ui.control.Button
        EmergencystopButton             matlab.ui.control.Button
        AutomaticControlPanel           matlab.ui.container.Panel
        MicrowavescanningPanel          matlab.ui.container.Panel
        VNAPanel                        matlab.ui.container.Panel
        StartmicroscanButton            matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DistancebetweenpointsSpinner    matlab.ui.control.Spinner
        DistancebetweenpointsSpinnerLabel  matlab.ui.control.Label
        NrofpointsSpinner               matlab.ui.control.Spinner
        NrofpointsSpinnerLabel          matlab.ui.control.Label
        ScanmodeButtonGroup             matlab.ui.container.ButtonGroup
        ScanselectionofpointsButton     matlab.ui.control.ToggleButton
        ScanareaButton                  matlab.ui.control.ToggleButton
        ScanwholeobjectButton           matlab.ui.control.ToggleButton
        DmodelreconstructionPanel       matlab.ui.container.Panel
        DatahandelingButtonGroup        matlab.ui.container.ButtonGroup
        ComplimentdataButton            matlab.ui.control.ToggleButton
        ReplacedataButton               matlab.ui.control.ToggleButton
        ScanningprotocolDropDown        matlab.ui.control.DropDown
        ScanningprotocolDropDownLabel   matlab.ui.control.Label
        ScanmodelButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        objPoints;
        shape;
        yumiTCP;
        k;
        diffRLPlotX;
        diffRLPlotY;
        diffRLPlotZ;
        testingCoord;

        %coordinateSystem = app.CoordinateSystemDropDown.value;  % Description
        %Property2 % Description
    end
    
    methods (Access = private)
       
        function con = connectToRobotTCP(app)
            
        end
        
        function results = testIfStepAllowed(app, newCoord)
            %[in, center] = inhull(newCoord, app.objPoints);
            %k = delaunayn(objectCoord);
            %in = ~isnan(tsearchn(objectCoord, app.k, newCoord));
            %diffNewX = app.yumiTCP.XData - newCoord(1);
            %diffNewY = app.yumiTCP.YData - newCoord(2);
            %diffNewZ = app.yumiTCP.ZData - newCoord(3);
            testCoord = newCoord;

            if newCoord(1) > 0
                testCoord(1) = newCoord(1) - app.MinimumdistancetoobjectSpinner.Value;
            else
                testCoord(1) = newCoord(1) + app.MinimumdistancetoobjectSpinner.Value;
            end

            if newCoord(2) > 0 
                testCoord(2) = newCoord(2) - app.MinimumdistancetoobjectSpinner.Value;
            else
                testCoord(2) = newCoord(2) + app.MinimumdistancetoobjectSpinner.Value;
            end

            if newCoord(3) > 0 
                testCoord(3) = newCoord(3) - app.MinimumdistancetoobjectSpinner.Value;
            else
                testCoord(3) = newCoord(3) + app.MinimumdistancetoobjectSpinner.Value;
            end

            if isempty(app.testingCoord)
                %app.testingCoord = plot3(app.UIAxes, testCoord(1), testCoord(2), testCoord(3),'.','MarkerSize',10, 'Color','cyan');
            else
                app.testingCoord.XData = testCoord(1);
                app.testingCoord.YData = testCoord(2);
                app.testingCoord.ZData = testCoord(3);

            end

            %lineToCenter = [newCoord; [0,0,0]];
            %plot3(app.UIAxes, lineToCenter(1), lineToCenter(2), lineToCenter(3),'-','MarkerSize',10, 'Color','cyan');

            %newCord(1) -3
            
           
            %Get angles of vector to X, Y, Z axis
            %angleFromX = atan(newCoord(2)/newCoord(1));
            %angleFromY = atan(newCoord(1)/newCoord(2));
            %angleFromZ = atan(newCoord(3)/newCoord(1));

            angleFromX = atan2(sqrt(newCoord(2)^2+newCoord(3)^2),newCoord(1));
            angleFromY = atan2(sqrt(newCoord(3)^2+newCoord(1)^2),newCoord(2));
            angleFromZ = atan2(sqrt(newCoord(1)^2+newCoord(2)^2),newCoord(3));
            
            %Get length of vector from origo
            lenFromOrigo = sqrt(newCoord(1)^2+newCoord(2)^2+newCoord(3)^2);

            %Calculate new vector based on angles and new length

            newLength = lenFromOrigo - app.MinimumdistancetoobjectSpinner.Value;
            
            %if angleFromX <= ... then sin else cos
            newShorterVector(1) = newLength * cos(angleFromX);
            newShorterVector(2) = newLength * cos(angleFromY);
            newShorterVector(3) = newLength * cos(angleFromZ);
            %plot3(app.UIAxes, newShorterVector(1), newShorterVector(2), newShorterVector(3),'.','MarkerSize',10, 'Color','cyan');
            %plot3(app.UIAxes, [newCoord(1) 0], [newCoord(2) 0], [newCoord(3) 0])

            newShorterVectorNoZ(1) = newShorterVector(1);
            newShorterVectorNoZ(2) = newShorterVector(2);
            newShorterVectorNoZ(3) = newCoord(3);

            %plot3(app.UIAxes, newShorterVectorNoZ(1), newShorterVectorNoZ(2), newShorterVectorNoZ(3),'.','MarkerSize',10, 'Color','blue');


            in = inShape(app.shape, newShorterVector) | inShape(app.shape, newShorterVectorNoZ);
            results = ~in;
            %plot3(app.UIAxes,center(1), center(2),
            %center(3),'.','MarkerSize',10, 'Color','Red');
            if in
                o = app.LogsTextArea.Value;
                t = now;
                d = string(datetime(t,'ConvertFrom','datenum'));
                test = string(app.LogsTextArea.Value);
                newText = d + ': Movement denied: New coordinate would be in object or within minimum distance set to object.';
                app.LogsTextArea.Value = vertcat(test, newText);
                %n = length(app.LogsTextArea.Value);
                %app.LogsTextArea.Value{n+1, 1} = text;
                %test = append(app.LogsTextArea.Value, newline, ' Movement denied: New coordinate would be in object.');
                %app.LogsTextArea.Value = test;
                %app.LogsTextArea.Value = join(test);
            end
            
        end
        
        function updateTCPcoord(app)
            set(app.yumiTCP,'XData',app.yumiTCP.XData,'YData',app.yumiTCP.YData, 'ZData', app.yumiTCP.ZData);
             if app.CoordinateSystemDropDown.Value == "Carteasian"
                app.tcpCordX.Text = string(app.yumiTCP.XData);
                app.tcpCordY.Text = string(app.yumiTCP.YData);
                app.tcpCordZ.Text = string(app.yumiTCP.ZData);


            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);
                app.tcpCordX.Text = string(r);
                app.tcpCordY.Text = string(theta);
                app.tcpCordZ.Text = string(z);

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);
                app.tcpCordX.Text = string(phi);
                app.tcpCordY.Text = string(theta);
                app.tcpCordZ.Text = string(rho);


            end

           
        end
        
        function newP = transPlottoRL(app, P)

            newP(:, 1) = P(:,1) - app.diffRLPlotX;
            newP(:, 2) = P(:,2) - app.diffRLPlotY;
            newP(:, 3) = P(:,3) - app.diffRLPlotZ;

            
        end
        
        function  newP = transRLtoPlot(app, P)
            if isempty(app.diffRLPlotX)
                centerX = mean(P(:, 1));
                centerY = mean(P(:, 2));
                centerZ = mean(P(:, 3));

                app.diffRLPlotX = -centerX;
                app.diffRLPlotY = -centerY;
                app.diffRLPlotZ = -centerZ;

            end

            newP(:, 1) = P(:,1) + app.diffRLPlotX;
            newP(:, 2) = P(:,2) + app.diffRLPlotY;
            newP(:, 3) = P(:,3) + app.diffRLPlotZ;
           
        end
        
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanmodelButton
        function ScanmodelButtonPushed(app, event)
            rng(0,'twister')


[x1,y1,z1] = ellipsoid(1,1,1,5.5,5.5,10);
x1 = x1(:);
y1 = y1(:);
z1 = z1(:);
x2 = x1+5;
p = [x1 y1 z1];
p = unique(p,'rows');
n = length(p);
j = 1;
for i = 1:n
    if p(i,3) <= 0
      Pn(j,:) = p(i, :);
      j = j+1;
        
    end
end
Pn = transRLtoPlot(app, Pn);
app.objPoints = Pn;
%k = delaunayn(Pn);
%app.k = k;
hold(app.UIAxes,'on')
%plot3(app.UIAxes, P(:,1),P(:,2),P(:,3),'.','MarkerSize',10);
grid(app.UIAxes, 'on')
%tetramesh(k, Pn, 'Parent', app.UIAxes);
plot3(app.UIAxes, Pn(:,1),Pn(:,2),Pn(:,3),'.')
%axis equal
shp = alphaShape(Pn(:,1),Pn(:,2),Pn(:,3),1);
shp.Alpha = 5.5;
plot(shp, 'Parent', app.UIAxes);
%axis equal
app.yumiTCP = plot3(app.UIAxes, 6, 6, 0,'.','MarkerSize',100, 'Color','Red');
updateTCPcoord(app);
app.shape = shp;
%hold(app.UIAxes,'off')
        end

        % Value changed function: CoordinateSystemDropDown
        function CoordinateSystemDropDownValueChanged(app, event)
            updateTCPcoord(app);
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                app.XLabel.Text = "X";
                app.YLabel.Text = "Y";
                app.ZLabel.Text = "Z";

                app.XLabel_2.Text = "X";
                app.YLabel_2.Text = "Y";
                app.ZLabel_2.Text = "Z";


            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                app.XLabel.Text = "R";
                app.YLabel.Text = "θ";
                app.ZLabel.Text = "Z";

                app.XLabel_2.Text = "R";
                app.YLabel_2.Text = "θ";
                app.ZLabel_2.Text = "Z";

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                app.XLabel.Text = "Φ";
                app.YLabel.Text = "θ";
                app.ZLabel.Text = "ρ";

                app.XLabel_2.Text = "Φ";
                app.YLabel_2.Text = "θ";
                app.ZLabel_2.Text = "ρ";

            end
            
            
        end

        % Button pushed function: manualXRightBtn
        function manualXRightBtnPushed(app, event)
            %ylim = get(app.UIAxes, 'YLim');
            %xlim = get(app.UIAxes, 'XLim');
            %zlim = get(app.UIAxes, 'ZLim');
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.XData + app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([newStep, app.yumiTCP.YData, app.yumiTCP.ZData]);
                if allowed
                    app.yumiTCP.XData = newStep;
                    updateTCPcoord(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r + app.StepsizeSpinner.Value, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end
            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi + app.StepsizeSpinner.Value, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            end


        end

        % Button pushed function: ManualXLeftBtn
        function ManualXLeftBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.XData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([newStep, app.yumiTCP.YData, app.yumiTCP.ZData]);
                if allowed
                    app.yumiTCP.XData = newStep;
                    updateTCPcoord(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r - app.StepsizeSpinner.Value, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi - app.StepsizeSpinner.Value, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            end
        end

        % Button pushed function: ManualZUpBtn
        function ManualZUpBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.ZData + app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.yumiTCP.XData, app.yumiTCP.YData, newStep]);
                if allowed
                    app.yumiTCP.ZData = newStep;
                    updateTCPcoord(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r, z + app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi, rho + app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            end
        end

        % Button pushed function: ManualZDownBtn
        function ManualZDownBtnButtonPushed(app, event)
              if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.ZData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.yumiTCP.XData, app.yumiTCP.YData, newStep]);
                if allowed
                    app.yumiTCP.ZData = newStep;
                    updateTCPcoord(app);
                end
              elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r, z - app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

              elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi, rho - app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            end
        end

        % Button pushed function: manualYRightBtn
        function manualYRightBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.YData + app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.yumiTCP.XData, newStep, app.yumiTCP.ZData]);
                if allowed
                    app.yumiTCP.YData = newStep;
                    updateTCPcoord(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta + app.StepsizeSpinner.Value, r, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta + app.StepsizeSpinner.Value, phi, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            end
        end

        % Button pushed function: ManualYLeftBtn
        function ManualYLeftBtnButtonPushed(app, event)
             if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.yumiTCP.YData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.yumiTCP.XData, newStep, app.yumiTCP.ZData]);
                if allowed
                    app.yumiTCP.YData = newStep;
                    updateTCPcoord(app);
                end
             elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta - app.StepsizeSpinner.Value, r, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

             elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.yumiTCP.XData,app.yumiTCP.YData, app.yumiTCP.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta - app.StepsizeSpinner.Value, phi, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.yumiTCP.XData = newStepX;
                    app.yumiTCP.YData = newStepY;
                    app.yumiTCP.ZData = newStepZ;
                    updateTCPcoord(app);
                end

            end
        end

        % Value changed function: StepsizeSpinner
        function StepsizeSpinnerValueChanged(app, event)

            
        end

        % Button pushed function: DriveawayfromobjectButton
        function DriveawayfromobjectButtonPushed(app, event)
            if app.yumiTCP.XData > 0
                app.yumiTCP.XData = app.yumiTCP.XData + 5;
            else
                app.yumiTCP.XData = app.yumiTCP.XData - 5;
            end

            if app.yumiTCP.YData > 0
                app.yumiTCP.YData = app.yumiTCP.YData + 5;
            else
                app.yumiTCP.YData = app.yumiTCP.YData - 5;
            end

            updateTCPcoord(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Object reconstruction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AutomaticControlPanel = uipanel(app.UIFigure);
            app.AutomaticControlPanel.Title = 'Automatic Control';
            app.DmodelreconstructionPanel = uipanel(app.AutomaticControlPanel);
            app.DmodelreconstructionPanel.Title = '3D-model reconstruction';
            app.ScanmodelButton = uibutton(app.DmodelreconstructionPanel, 'push');
            app.ScanmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ScanmodelButtonPushed, true);
            app.ScanmodelButton.Text = 'Scan model';
            app.ScanningprotocolDropDownLabel = uilabel(app.DmodelreconstructionPanel);
            app.ScanningprotocolDropDownLabel.Text = 'Scanning protocol';
            app.ScanningprotocolDropDown = uidropdown(app.DmodelreconstructionPanel);
            app.DatahandelingButtonGroup = uibuttongroup(app.DmodelreconstructionPanel);
            app.DatahandelingButtonGroup.Title = 'Data handeling';
            app.ReplacedataButton = uitogglebutton(app.DatahandelingButtonGroup);
            app.ReplacedataButton.Text = 'Replace data';
            app.ReplacedataButton.Value = true;
            app.ComplimentdataButton = uitogglebutton(app.DatahandelingButtonGroup);
            app.ComplimentdataButton.Text = 'Compliment data';
            app.MicrowavescanningPanel = uipanel(app.AutomaticControlPanel);
            app.MicrowavescanningPanel.Title = 'Microwave scanning';
            app.ScanmodeButtonGroup = uibuttongroup(app.MicrowavescanningPanel);
            app.ScanmodeButtonGroup.Title = 'Scan mode';
            app.ScanwholeobjectButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanwholeobjectButton.Text = 'Scan whole object';
            app.ScanwholeobjectButton.Value = true;
            app.ScanareaButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanareaButton.Text = 'Scan area';
            app.ScanselectionofpointsButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanselectionofpointsButton.Text = 'Scan selection of points';
            app.NrofpointsSpinnerLabel = uilabel(app.MicrowavescanningPanel);
            app.NrofpointsSpinnerLabel.Text = 'Nr of points';
            app.NrofpointsSpinner = uispinner(app.MicrowavescanningPanel);
            app.DistancebetweenpointsSpinnerLabel = uilabel(app.MicrowavescanningPanel);
            app.DistancebetweenpointsSpinnerLabel.Text = 'Distance between points';
            app.DistancebetweenpointsSpinner = uispinner(app.MicrowavescanningPanel);
            app.UITable = uitable(app.MicrowavescanningPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.StartmicroscanButton = uibutton(app.MicrowavescanningPanel, 'push');
            app.StartmicroscanButton.Text = 'Start micro scan';
            app.VNAPanel = uipanel(app.MicrowavescanningPanel);
            app.VNAPanel.Title = 'VNA';
            app.EmergencystopButton = uibutton(app.UIFigure, 'push');
            app.EmergencystopButton.Text = 'Emergency stop';
            app.ManualControlPanel = uipanel(app.UIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.ManualZUpBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualZUpBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZUpBtnButtonPushed, true);
            app.ManualZUpBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.ManualZUpBtn.Text = '';
            app.ManualXLeftBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualXLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualXLeftBtnButtonPushed, true);
            app.ManualXLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualXLeftBtn.Text = '';
            app.manualXRightBtn = uibutton(app.ManualControlPanel, 'push');
            app.manualXRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualXRightBtnPushed, true);
            app.manualXRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualXRightBtn.Text = '';
            app.ManualZDownBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualZDownBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZDownBtnButtonPushed, true);
            app.ManualZDownBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualZDownBtn.Text = '';
            app.CoordinateSystemDropDownLabel = uilabel(app.ManualControlPanel);
            app.CoordinateSystemDropDownLabel.Text = 'Coordinate System';
            app.CoordinateSystemDropDown = uidropdown(app.ManualControlPanel);
            app.CoordinateSystemDropDown.Items = {'Carteasian', 'Cylindrical', 'Spherical'};
            app.CoordinateSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemDropDownValueChanged, true);
            app.CoordinateSystemDropDown.Value = 'Carteasian';
            app.MinimumdistancetoobjectSpinnerLabel = uilabel(app.ManualControlPanel);
            app.MinimumdistancetoobjectSpinnerLabel.Text = 'Minimum distance to object';
            app.MinimumdistancetoobjectSpinner = uispinner(app.ManualControlPanel);
            app.MinimumdistancetoobjectSpinner.Step = 0.5;
            app.MinimumdistancetoobjectSpinner.Limits = [0 10];
            app.manualYRightBtn = uibutton(app.ManualControlPanel, 'push');
            app.manualYRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualYRightBtnButtonPushed, true);
            app.manualYRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualYRightBtn.Text = '';
            app.ManualYLeftBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualYLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualYLeftBtnButtonPushed, true);
            app.ManualYLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualYLeftBtn.Text = '';
            app.XLabel = uilabel(app.ManualControlPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.ManualControlPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.ManualControlPanel);
            app.ZLabel.Text = 'Z';
            app.StepsizeSpinnerLabel = uilabel(app.ManualControlPanel);
            app.StepsizeSpinnerLabel.Text = 'Step size';
            app.StepsizeSpinner = uispinner(app.ManualControlPanel);
            app.StepsizeSpinner.Step = 0.1;
            app.StepsizeSpinner.Limits = [0 10];
            app.StepsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @StepsizeSpinnerValueChanged, true);
            app.StepsizeSpinner.Value = 0.1;
            app.mmLabel = uilabel(app.ManualControlPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.ManualControlPanel);
            app.mmLabel_2.Text = 'mm';
            app.XLabel_2 = uilabel(app.ManualControlPanel);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.ManualControlPanel);
            app.YLabel_2.Text = 'Y';
            app.Label = uilabel(app.ManualControlPanel);
            app.Label.Text = '=';
            app.Label_2 = uilabel(app.ManualControlPanel);
            app.Label_2.Text = '=';
            app.tcpCordX = uilabel(app.ManualControlPanel);
            app.tcpCordX.Text = '0';
            app.tcpCordY = uilabel(app.ManualControlPanel);
            app.tcpCordY.Text = '0';
            app.TCPcoordinatesLabel = uilabel(app.ManualControlPanel);
            app.TCPcoordinatesLabel.Text = 'TCP coordinates:';
            app.Panel = uipanel(app.ManualControlPanel);
            app.ZLabel_2 = uilabel(app.Panel);
            app.ZLabel_2.Text = 'Z';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '=';
            app.tcpCordZ = uilabel(app.Panel);
            app.tcpCordZ.Text = '0';
            app.DriveawayfromobjectButton = uibutton(app.UIFigure, 'push');
            app.DriveawayfromobjectButton.ButtonPushedFcn = createCallbackFcn(app, @DriveawayfromobjectButtonPushed, true);
            app.DriveawayfromobjectButton.Text = 'Drive away from object';
            app.LogsTextAreaLabel = uilabel(app.UIFigure);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.UIFigure);
            app.LogsTextArea.Value = {' '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestLayout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end